classdef spring_mass_damper_simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SystemLabel                     matlab.ui.control.Label
        cDampingNsmLabel                matlab.ui.control.Label
        c_slider                        matlab.ui.control.Slider
        kSpringConstantNmLabel          matlab.ui.control.Label
        k_slider                        matlab.ui.control.Slider
        mMasskgLabel                    matlab.ui.control.Label
        m_slider                        matlab.ui.control.Slider
        v_0IntialVelocitymLabel         matlab.ui.control.Label
        v_0_slider                      matlab.ui.control.Slider
        x_0InitialPositionmLabel        matlab.ui.control.Label
        x_0_slider                      matlab.ui.control.Slider
        m_0                             matlab.ui.control.Button
        v_0_0                           matlab.ui.control.Button
        x_0_0                           matlab.ui.control.Button
        k_0                             matlab.ui.control.Button
        c_0                             matlab.ui.control.Button
        x_0_R                           matlab.ui.control.Button
        v_0_R                           matlab.ui.control.Button
        m_R                             matlab.ui.control.Button
        k_R                             matlab.ui.control.Button
        c_R                             matlab.ui.control.Button
        F_max_0                         matlab.ui.control.Button
        F_max_R                         matlab.ui.control.Button
        F_maxSatLimMLabel               matlab.ui.control.Label
        F_max_slider                    matlab.ui.control.Slider
        sat_lim_check_box               matlab.ui.control.CheckBox
        s_plane                         matlab.ui.control.UIAxes
        CenterPanel                     matlab.ui.container.Panel
        animation                       matlab.ui.control.UIAxes
        x_plot                          matlab.ui.control.UIAxes
        e_plot                          matlab.ui.control.UIAxes
        v_plot                          matlab.ui.control.UIAxes
        F_plot                          matlab.ui.control.UIAxes
        I_plot                          matlab.ui.control.UIAxes
        play_button                     matlab.ui.control.Button
        t_0                             matlab.ui.control.Button
        t_R                             matlab.ui.control.Button
        tAnimationTimesLabel            matlab.ui.control.Label
        t_slider                        matlab.ui.control.Slider
        RightPanel                      matlab.ui.container.Panel
        ControllerLabel                 matlab.ui.control.Label
        open_loop_check_box             matlab.ui.control.CheckBox
        PID_check_box                   matlab.ui.control.CheckBox
        full_state_feeback_check_box    matlab.ui.control.CheckBox
        FInputNLabel                    matlab.ui.control.Label
        F_slider                        matlab.ui.control.Slider
        PProportionalGainLabel          matlab.ui.control.Label
        P_slider                        matlab.ui.control.Slider
        IIntegratorGainLabel            matlab.ui.control.Label
        I_slider                        matlab.ui.control.Slider
        DDerivativeGainLabel            matlab.ui.control.Label
        D_slider                        matlab.ui.control.Slider
        rCommandmLabel                  matlab.ui.control.Label
        r_slider                        matlab.ui.control.Slider
        r_0                             matlab.ui.control.Button
        F_0                             matlab.ui.control.Button
        P_0                             matlab.ui.control.Button
        I_0                             matlab.ui.control.Button
        D_0                             matlab.ui.control.Button
        p1_0                            matlab.ui.control.Button
        r_R                             matlab.ui.control.Button
        F_R                             matlab.ui.control.Button
        P_R                             matlab.ui.control.Button
        I_R                             matlab.ui.control.Button
        D_R                             matlab.ui.control.Button
        p1_R                            matlab.ui.control.Button
        v_limAntiwindupmsLabel          matlab.ui.control.Label
        v_lim_slider                    matlab.ui.control.Slider
        antiwindup_check_box            matlab.ui.control.CheckBox
        v_lim_0                         matlab.ui.control.Button
        v_lim_R                         matlab.ui.control.Button
        FeedbackLabel                   matlab.ui.control.Label
        full_state_feedback_integrator_check_box  matlab.ui.control.CheckBox
        Pole1RealComponentLabel         matlab.ui.control.Label
        p1_slider                       matlab.ui.control.Slider
        p2_0                            matlab.ui.control.Button
        p2_R                            matlab.ui.control.Button
        Pole2RealComponentLabel         matlab.ui.control.Label
        p2_slider                       matlab.ui.control.Slider
        p3_0                            matlab.ui.control.Button
        p3_R                            matlab.ui.control.Button
        IntegratorRealComponentLabel    matlab.ui.control.Label
        p3_slider                       matlab.ui.control.Slider
        conjugate_check_box             matlab.ui.control.CheckBox
        imag_0                          matlab.ui.control.Button
        imag_R                          matlab.ui.control.Button
        Poles12ImaginaryComponentLabel  matlab.ui.control.Label
        imag_slider                     matlab.ui.control.Slider
        dc_gain_check_box               matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

%{
    9/4/2020
    Linear Controls Review Tool
    Created for AERO 625 at Texas A&M
    Created and copyrighted by Kameron Eves
    Contact Kameron Eves with questions: KameronEves@gmail.com
%}
    properties (Access = private)
        % Parameters
        param                       % System parameters
        control                     % Control parameters
        
        % Time variables
        t_step = 0.01;              % Time step
        t_f    = 20;                % Final time
        
        % Values for plotting
        x_hist                      % Saved value of x over time
        r_hist                      % Saved value of r over time
        e_hist                      % Saved value of e over time
        v_hist                      % Saved value of v over time
        I_hist                      % Saved value of I over time
        F_hist                      % Saved value of F over time
        t_hist                      % Saved value of t over time
        
        % Line handles to alter without redrawing
        x_line                      % Line plot of x
        r_line                      % Line plot of r
        e_line                      % Line plot of e
        v_line                      % Line plot of v
        I_line                      % Line plot of I
        F_line                      % Line plot of F
        
        % Animation handles to alter without redrawing
        start_line                  % Initial position of the mass
        goal_line                   % Commanded position of the mass
        box                         % Actual mass
        animation_start             % Intial time of the animation
        animation_offset = 0        % Time offset 
                                    % Created when animation is paused
        animation_completed         % Has the current animation finished. 
                                    % Stops the system from repeating old 
                                    % animations if the animation is 
                                    % changed on the fly.
        paused = true               % Is the animation puased.
        
        % Animation handles for points on the line plot indicating the 
        % time of the animation.
        x_point                     % Position on the x plot.
        e_point                     % Position on the e plot.
        v_point                     % Position on the v plot.
        I_point                     % Position on the I plot.
        F_point                     % Position on the F plot.
        
        % System eigenvalues
        ol_eig                      % Open loop eigenvalues
        cl_eig                      % Closed loop eigenvalues
        gl_eig                      % Desired eigenvalues
        
        % Plot handles for the eigenvalues
        ol_eig_points               % Open loop eigenvalues
        cl_eig_points               % Closed loop eigenvalues               
        gl_eig_points               % Goal eigenvalues
        
        % Default values for the reset button on the sliders
        default_values = struct('x_0', -1, ...
                                'v_0',  0, ...
                                'm',    5, ...
                                'k',    3, ...
                                'c',    0.5, ...
                                't',    0, ...
                                'r',    1, ...
                                'F',    0, ...
                                'P',    0, ...
                                'I',    0, ...
                                'D',    0, ...
                                'F_max',5, ...
                                'v_lim',0.4, ...
                                'poles',[-1+0.3i,-1-0.3i,-0.5])
    end
    
    methods (Access = private)
        
% Main Function -----------------------------------------------------------

        function simulate(app)
            %{
            This function runs the acutal simulation step by step and after
            completion plots the results.
            %} 
            
            app.animation_offset = 0; % Restart the animation.
            
            if app.param.m == 0
                app.param.m = 0.01; % Mass can not be 0 both physically and 
                                    % this avoids devision by 0.
            end
            
            % Initalize system
            app.param.A = [0                       , 1;
                           -app.param.k/app.param.m, ...
                           -app.param.c/app.param.m];
            app.param.B = [0;
                           1/app.param.m];
            app.param.C = [1,0];
            app.param.D = [0];
            
            % Get the eigenvalues
            app.get_eig();
            
            % If using full state feedback then we need to calculate the
            % gains from the given poles.
            if strcmp(app.control.type,'fsf')
                app.calculate_gains();
            end
            
            % Initalize values to save.
            x = app.param.x_0;
            r = app.control.r;
            e = r-x(1);
            I = 0; 
            u = app.get_u(x,e,x(2),I,r);
            
            % Save initial values.
            app.x_hist(1) = x(1);
            app.r_hist(1) = app.control.r;
            app.e_hist(1) = e;
            app.v_hist(1) = x(2);
            app.I_hist(1) = I;
            app.F_hist(1) = u;
            
            % For each time step.
            for i = 2:length(app.t_hist)
                % Get the input from the control algorithms
                u = app.get_u(x,e,x(2),I,r);
                
                % Simulate the system for 1 timestep.
                x = app.numerical_integration(x,u);
                
                % Calculate saved values
                e = r - x(1);
                % Integrator only needs to be summed if antiwindup is off
                % or the antiwindup velocity is exceeded.
                if ~app.antiwindup_check_box.Value ...
                   || (app.antiwindup_check_box.Value ...
                       && abs(x(2))<app.control.v_lim)
                    % Use numerical integration (trapazoidal method) 
                    % to update the integrator.
                    I = I + trapz([app.e_hist(i-1),e])*app.t_step;
                end
                
                % Save values for plotting later.
                app.x_hist(i) = x(1);
                app.r_hist(i) = app.control.r;
                app.e_hist(i) = e;
                app.v_hist(i) = x(2);
                app.I_hist(i) = I;
                app.F_hist(i) = u;
            end
            
            app.plot(); % Update the plots.
        end
        
% Control Theory ----------------------------------------------------------

% NOTE: The theory from this is from The Control Book, by Randy Beard, Tim
% McLain, and Cammy Peterson. A fee copy can be found here:
% controlbook.byu.edu

        function x_dot = eqs_motion(app,x,u)
            %{
            This function is the equations of motion. The system is already
            linearized. This model (x_dot = Ax + Bu) would not be valid for 
            a nonlinear system.
            %}
            x_dot = app.param.A*x + app.param.B*u;
        end    

        function u = get_u(app,x,e,v,I,r)
            %{
            This function returns the input as calculated by a control
            algorithm.
            x - nx1 - the current state vector
            e - The error between the actual and commanded position
            v - The velocity of the system (theoretically this should be 
                the velocity of the error, but this leads to a zero that 
                can cause a poor responce)
            I - The integrator or the summed up error over time.
            r - The commanded position.
            %}
            
            switch app.control.type
                case 'ol' % Open loop
                    u = app.control.F;
                case 'pid' % PID
                    u = app.control.P*e + app.control.I*I ...
                        + app.control.D*(-v); % See the first equation in 
                                              % section 10.1 of the text.
                case 'fsf' % Full state feedback
                    % An integrator and setting the DC gain to 1 do not
                    % need to be done at the same time.
                    if app.full_state_feedback_integrator_check_box.Value
                        % The 7th equation in section 12.1 of the text
                        % (at time of writing this is the 1st equation on
                        % page 183 of the text.)
                        u = -app.control.K*x - app.control.I*I;
                    else
                        % Equation 11.31 of the text.
                        u = -app.control.K*x + app.control.K_r*r;
                    end
            end
            
            % Ensure the commanded force is within saturation limits
            if app.sat_lim_check_box.Value
                u = min(app.param.F_max,u);
                u = max(-app.param.F_max,u);
            end
        end
        
        function get_eig(app)
            %{
            Get the eigenvalues of the system. The techinque for this
            changes depending on the type of control used.
            %}
            
            % Open loop eigenvalues don't change.
            app.ol_eig = eig(app.param.A);
            
            % The method of obtaining the closed loop eigen values changes
            % with each control method.
            switch app.control.type
                case 'ol' % Open Loop
                    app.cl_eig = eig(app.param.A); % The same as open loop.
                case 'pid' % PID
                    % Get the open loop (plant) transfer function
                    [p_num,p_den] = ss2tf(app.param.A,app.param.B,app.param.C,app.param.D);
                    
                    % Get the control algorithm's transfer function.
                    c_num = [app.control.D,app.control.P,app.control.I];
                    c_den = [1,0];
                    
                    % Multiply the polanomials according to second eqation
                    % in section 9.1.2 of the text. Note that I use state
                    % X instead of the commanded position R. So we have PC
                    % in the numerator as well.
                    pc_num = conv(p_num,c_num);
                    pc_den = conv(p_den,c_den);
                    cl_num = pc_num;
                    cl_den = app.polysum(pc_den,pc_num);
                    
                    % Get the roots of the charictoristic equation
                    cl_num_roots = roots(cl_num);
                    cl_den_roots = roots(cl_den);
                    
                    % Ensure there are no roots in the numerator and the
                    % denominator that cancel out.
                    app.cl_eig = complex(setdiff(cl_den_roots,cl_num_roots));
                case 'fsf'
                    % This is the charictoristic equation according to the
                    % denominator that would ocurr while calculating
                    % equation 11.33 of the text.
                    app.cl_eig = eig(app.param.A-app.param.B*app.control.K);
            end
        end
        
        function calculate_gains(app)
            % For ease of reading save local versions of teh variables.
            A = app.param.A;
            B = app.param.B;
            C = app.param.C;
            
            % If using an integrator augment the matricies according to
            % equation 12.1 of the text.
            if app.full_state_feedback_integrator_check_box.Value
                A = [A,  zeros([length(A(:,1)),1]);
                     -C, 0];
                B = [B;0];
                
                % Because we added a pole for the integrator the open loop
                % poles need to be calculated again.
                app.ol_eig = eig(A);
            end
            
            % Intialize variables.
            C_A_B = zeros(size(A));
            A_A   = zeros(size(A));
            
            % Build the controlability matrix according to equation 11.20
            % of the text.
            for i = 1:length(C_A_B(1,:))
                C_A_B(:,i) = A^(i-1)*B;
            end
            
            % Calculate the charictoristic equation of the open loop system
            % by mulitplying sets of (1-lambda) where lambda is a root.
            den = [1];
            for i = 1:length(app.ol_eig)
                den = conv(den,[1,-app.ol_eig(i)]);
            end
            
            % Calculate the A_A matrix according to equation 11.30 of the
            % text.
            for i = 1:length(A_A(:,1))
                A_A(i,:) = [zeros(1,i-1),den(1:end-i)];
            end
            
            % Calculate the desired charactoristic equation after control
            % is applied by mulitplying sets of (1-lambda) where lambda is 
            % a root.
            den_des = [1];
            for i = 1:length(app.gl_eig)
                den_des = conv(den_des,[1,-app.gl_eig(i)]);
            end
            
            % Remove the leading 1 and rename the vectors to match the
            % notation in the text book.
            a_a = den(2:end);
            alpha = den_des(2:end);
            
            % Calculate the gains using Ackermann's formula 
            % (equation 11.32)
            app.control.K = (alpha - a_a)/A_A/C_A_B;
            
            % The DC gain of this system is probably not 1 leaving some
            % steady state error. Remove this by multiplying the numerator
            % by the value calcuated. (equation 11.36 of the text)
            if app.dc_gain_check_box.Value
                app.control.K_r = -1/(C/(A-B*app.control.K)*B);
            else % this will leave the DC gain as what it inerently is.
                app.control.K_r = 1;
            end
            
            % Calculate teh closed loop eigenvalues using the new gains.
            % This is done in the get_eig function, but these gains are
            % calculated after that function is called. See that function
            % for details.
            app.cl_eig = eig(A-B*app.control.K);
            app.ol_eig = eig(app.param.A); % This corrects the fact that, 
                                           % if we're using an integrator 
                                           % we changed this variable 
                                           % previously.
            
            % If we're using an integrator we need to remove the integrator
            % gain from the lists of gains.
            if app.full_state_feedback_integrator_check_box.Value
                app.control.I = app.control.K(end);
                app.control.K = app.control.K(1:end-1);
            end
        end
        
% Animation and Plotting -------------------------------------------------
        
        function plot(app)
            %{
            Update everything except the animation. This runs everytime a
            change is made to the inputs (sliders or buttons).
            %}
            
            % Update the line plots.
            set(app.x_line,'YData',app.x_hist);
            set(app.r_line,'YData',app.r_hist);
            set(app.e_line,'YData',app.e_hist);
            set(app.v_line,'YData',app.v_hist);
            set(app.I_line,'YData',app.I_hist);
            set(app.F_line,'YData',app.F_hist);
            
            % Update the start and finish lines on the animation.
            set(app.start_line,'XData',[app.x_hist(1),app.x_hist(1)]);
            set(app.goal_line,'XData',[app.r_hist(1),app.r_hist(1)]);
            
            % Update the plot of the eigen values.
            set(app.ol_eig_points,'XData',real(app.ol_eig),'YData',imag(app.ol_eig));
            set(app.cl_eig_points,'XData',real(app.cl_eig),'YData',imag(app.cl_eig));
            set(app.gl_eig_points,'XData',real(app.gl_eig),'YData',imag(app.gl_eig));
            
            % Wait for the drawing to finish
            drawnow;
            
            % Pause and reset the animation whenever the simulation is 
            % moving a slider or clicking on a button.
            app.paused = true;
            set(app.play_button,'Text','Play');
            app.animation_offset = 0;
            
            % Redraw the animation once
            app.animate_once(0);
        end
        
        function animate(app)
            %{
            This redraws teh animation over and over in real time to make
            it seem like the images are moving.
            %}
            
            % This variable ensure that, if the animation is interupted it
            % will play with only the new values and not go back to
            % finish animating the old system.
            app.animation_completed = false;
            
            % Start the timer for the real time simulation.
            app.animation_start = tic;
            
            % Initialize the animation at the start, the last paused point,
            % or the location of the time slider.
            i = floor(app.animation_offset/app.t_step)+1;
            
            % Until the animation is over.
            while (i <= length(app.t_hist))
                
                % Ensure that the animation hasn't been paused.
                if ~app.paused 
                    
                    % Check how far along in the simulation real time
                    % indicates you should be.
                    t = toc(app.animation_start)+app.animation_offset;
                    
                    % If the animation isn't over.
                    if (app.t_hist(i) <= t)
                        app.animate_once(t); % Draw the new frame of the 
                                             % animation
                        set(app.t_slider,'Value',app.t_hist(i)); % Update 
                                                                 % the
                                                                 % slider.
                        
                        i = ceil(t/app.t_step); % Update what timestep 
                                                % we're currenlty on
                    end
                end
                
                % If the animation is paused or a new animation has steped
                % in, then cancel this animation.
                if (app.paused || app.animation_completed)
                    return;
                end
            end
            
            % Mark that this animation completed.
            app.animation_completed = true;
            app.animation_offset = 0;
        end
        
        function animate_once(app,value)
            % Calculate what frame you're currenlty on.
            i = ceil(value/app.t_step);
            if i > length(app.t_hist)
                i = length(app.t_hist);
            elseif i < 1
                i = 1;
            end
            
            % Redraw the box, the starting line, and the commanded position
            set(app.box,'X',[0,1,1,0]*app.param.m/10+app.x_hist(i),'Y',[0,0,1,1]*app.param.m/10);
            set(app.start_line,'XData',[app.x_hist(1),app.x_hist(1)]);
            set(app.goal_line,'XData',[app.r_hist(1),app.r_hist(1)]);
            
            % Redraw the markers indicating the current time.
            set(app.x_point,'XData',app.t_hist(i),'YData',app.x_hist(i));
            set(app.e_point,'XData',app.t_hist(i),'YData',app.e_hist(i));
            set(app.v_point,'XData',app.t_hist(i),'YData',app.v_hist(i));
            set(app.I_point,'XData',app.t_hist(i),'YData',app.I_hist(i));
            set(app.F_point,'XData',app.t_hist(i),'YData',app.F_hist(i));
            
            % Puase to let the new image be displayed.
            drawnow;
        end
        
% Utilities ---------------------------------------------------------------
        
        function x = numerical_integration(app,x,u)
            %{
            This employees the Runga Kutta method of numerical integration
            See equation P.4.10 of the text.
            %}
            
            % rename this variable for ease of reading.
            t = app.t_step;
            
            % Runga Kutta Numerical Integration
            k1 = app.eqs_motion(x,          u);
            k2 = app.eqs_motion(x + t/2*k1, u);
            k3 = app.eqs_motion(x + t/2*k2, u);
            k4 = app.eqs_motion(x + t  *k3, u);
            x = x + t/6 * (k1 + 2*k2 + 2*k3 + k4);
        end
        
        function update_F_slider(app,k)
            if k >= 5
                k = ceil(k);
                set(app.F_slider,'Limits',[-1,1]*2*k);
            else
                set(app.F_slider,'Limits',[-10,10]);
            end
        end
        
        function type_selection(app,changed)
            %{
            Ensure only one type of control algorithm is selected at a 
            time.
            %}
            
            % Change the switch displays
            switch changed
                case 'ol'
                app.open_loop_check_box.Value          = true;
                app.PID_check_box.Value                = false;
                app.full_state_feeback_check_box.Value = false;
                case 'pid'
                app.open_loop_check_box.Value          = false;
                app.PID_check_box.Value                = true;
                app.full_state_feeback_check_box.Value = false;
                case 'fsf'
                app.open_loop_check_box.Value          = false;
                app.PID_check_box.Value                = false;
                app.full_state_feeback_check_box.Value = true;
            end
            
            % Update the algorithm that will be executed.
            app.control.type = changed;
        end
        
        function sum = polysum(~,poly1,poly2)
            %{
            In Matlab, polynomials are stored as a list of coefficents in
            descending order from the highest power to the lowest. Becaues
            two polinomials of different lenghts can be added but two
            vecotrs of different lenghts can't be, this function provides
            that capability.
            %}
            
            % Ensure that poly1 is the longest vector (the polynomial with
            % the highest order). Swap them if they are not.
            if length(poly2)>length(poly1)
                old_poly1 = poly1;
                old_poly2 = poly2;
                poly1 = old_poly2;
                poly2 = old_poly1;
            end
            
            % Append 0 to teh begining of poly2 until it's lenght is equal
            % to poly1
            while length(poly1)>length(poly2)
                poly2 = [0,poly2];
            end
            
            % Add the two polinomials together.
            sum = poly1 + poly2;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize system variables
            app.param.x_0 = [app.x_0_slider.Value;app.v_0_slider.Value];
            app.param.m = app.m_slider.Value;
            app.param.k = app.k_slider.Value;
            app.param.c = app.c_slider.Value;
            app.param.F_max = app.F_max_slider.Value;
            
            % Initialize control variables
            app.control.F = app.F_slider.Value;
            app.control.P = app.P_slider.Value;
            app.control.I = app.I_slider.Value;
            app.control.D = app.D_slider.Value;
            app.control.K = [0,0];
            app.control.K_r = 0;
            app.control.r = app.r_slider.Value;
            app.control.v_lim = app.v_lim_slider.Value;
            app.control.type = 'ol';
            
            % Initialize saved values for ploting
            app.x_hist = zeros(1,app.t_f/app.t_step+1);
            app.r_hist = zeros(1,app.t_f/app.t_step+1);
            app.e_hist = zeros(1,app.t_f/app.t_step+1);
            app.v_hist = zeros(1,app.t_f/app.t_step+1);
            app.I_hist = zeros(1,app.t_f/app.t_step+1);
            app.F_hist = zeros(1,app.t_f/app.t_step+1);
            app.t_hist = 0:app.t_step:app.t_f;
            
            % This ensures the plots line up. by limiting the significant
            % figures on the y axis labels.
            ytickformat(app.x_plot,'%+5.1f');
            ytickformat(app.e_plot,'%+5.1f');
            ytickformat(app.v_plot,'%+5.1f');
            ytickformat(app.I_plot,'%+5.1f');
            ytickformat(app.F_plot,'%+5.1f');
            
            % Allow ploting multiple values on one plot.
            hold(app.x_plot,"on");
            hold(app.e_plot,"on");
            hold(app.v_plot,"on");
            hold(app.I_plot,"on");
            hold(app.F_plot,"on");
            hold(app.animation,"on");
            hold(app.s_plane,"on");
            
            % Plot the initialized data and save the line handles.
            app.x_line = plot(app.x_plot,app.t_hist,app.x_hist,'b');
            app.r_line = plot(app.x_plot,app.t_hist,app.r_hist,'r');
            app.e_line = plot(app.e_plot,app.t_hist,app.e_hist,'b');
            app.v_line = plot(app.v_plot,app.t_hist,app.v_hist,'b');
            app.I_line = plot(app.I_plot,app.t_hist,app.I_hist,'b');
            app.F_line = plot(app.F_plot,app.t_hist,app.F_hist,'b');
            
            % Plot the animation components and save the handles. 
            app.start_line = plot(app.animation,[app.x_hist(1),app.x_hist(1)],[0,1],'k');
            app.goal_line  = plot(app.animation,[app.r_hist(1),app.r_hist(1)],[0,1],'r');
            app.box = fill(app.animation,[0,1,1,0]*app.param.m/10,[0,0,1,1]*app.param.m/10,'g');
            
            % Plot the time indicators on each plot and save the handles.
            app.x_point = plot(app.x_plot,app.t_hist(1),app.x_hist(1),'og','MarkerSize',7,'MarkerEdgeColor','k','MarkerFaceColor','g');
            app.e_point = plot(app.e_plot,app.t_hist(1),app.e_hist(1),'og','MarkerSize',7,'MarkerEdgeColor','k','MarkerFaceColor','g');
            app.v_point = plot(app.v_plot,app.t_hist(1),app.v_hist(1),'og','MarkerSize',7,'MarkerEdgeColor','k','MarkerFaceColor','g');
            app.I_point = plot(app.I_plot,app.t_hist(1),app.I_hist(1),'og','MarkerSize',7,'MarkerEdgeColor','k','MarkerFaceColor','g');
            app.F_point = plot(app.F_plot,app.t_hist(1),app.F_hist(1),'og','MarkerSize',7,'MarkerEdgeColor','k','MarkerFaceColor','g');
            
            % Initialize the eigvenvalues
            app.ol_eig = complex([0,0]);
            app.cl_eig = complex([0,0]);
            app.gl_eig = complex([0,0]);
            
            % Initialize the ploted points of the eigvenvalues and save the
            % handles.
            app.ol_eig_points = plot(app.s_plane,app.ol_eig,'s','MarkerSize',13,'MarkerEdgeColor','b','MarkerFaceColor','b','DisplayName','Open Loop');
            app.gl_eig_points = plot(app.s_plane,app.gl_eig,'o','MarkerSize',10,'MarkerEdgeColor','g','MarkerFaceColor','g','DisplayName','Goal');
            app.cl_eig_points = plot(app.s_plane,app.ol_eig,'^','MarkerSize',7,'MarkerEdgeColor','r','MarkerFaceColor','r','DisplayName','Closed Loop');
            legend(app.s_plane,'location','southoutside');
            
            % Set the length of the slider to match the lenght of the
            % simulation
            set(app.t_slider,'Limits',[0,app.t_f]);
            
            % Set the scale of the input force to be commiserate with the 
            % strength of the spring.
            app.update_F_slider(app.param.k);
            
            % Run the initial simulation.
            app.simulate()
            app.animate()
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {814, 814, 814};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {814, 814};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {311, '1x', 326};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Value changing function: x_0_slider
        function x_0_slider_changing(app, event)
                                            % Many of the following
                                            % callbacks follow this same
                                            % pattern.
            app.param.x_0(1) = event.Value; % Update the value
            app.simulate();                 % Run the simulation
        end

        % Value changing function: v_0_slider
        function v_0_slider_changing(app, event)
            app.param.x_0(2) = event.Value;
            app.simulate();
        end

        % Value changing function: m_slider
        function m_slider_changing(app, event)
            app.param.m = event.Value;
            app.simulate()
        end

        % Value changing function: k_slider
        function k_slider_changing(app, event)
            app.param.k = event.Value;
            app.simulate()
            app.update_F_slider(event.Value); % Scale the slider to a 
                                              % reasonable range for this 
                                              % spring constant
        end

        % Value changing function: c_slider
        function c_slider_changing(app, event)
            app.param.c = event.Value;
            app.simulate()
        end

        % Value changing function: F_max_slider
        function F_max_slider_changing(app, event)
            app.param.F_max = event.Value;
            app.simulate();
        end

        % Value changing function: t_slider
        function t_slider_changing(app, event)
            app.paused = true;                  % Pause for robustness
            set(app.play_button,'Text','Play');  % Change for a visual cue
            app.animation_offset = event.Value; % Save the initial position
                                                % of the slider for the
                                                % timer handling playback
            app.animate_once(event.Value);      % Update the animation 
                                                % (still paused)
        end

        % Value changing function: r_slider
        function r_slider_changing(app, event)
            app.control.r = event.Value;
            app.simulate();
        end

        % Value changing function: F_slider
        function F_slider_changing(app, event)
            app.control.F = event.Value;
            app.simulate()
        end

        % Value changing function: P_slider
        function P_slider_changing(app, event)
            app.control.P = event.Value;
            app.simulate();
        end

        % Value changing function: I_slider
        function I_slider_changing(app, event)
            app.control.I = event.Value;
            app.simulate();
        end

        % Value changing function: D_slider
        function D_slider_changing(app, event)
            app.control.D = event.Value;
            app.simulate();
        end

        % Value changing function: p1_slider
        function p1_slider_changing(app, event)
            app.gl_eig(1) = event.Value + imag(app.gl_eig(1))*1i;
            if app.conjugate_check_box.Value % If this is a conjugate pair 
                                           % then the other eigenvalue and 
                                           % slider need to be updated as 
                                           % well.
                app.gl_eig(2) = real(app.gl_eig(1)) - imag(app.gl_eig(1))*1i;
                app.p2_slider.Value = event.Value;
            end
            app.simulate();
        end

        % Value changing function: p2_slider
        function p2_slider_changing(app, event)
            app.gl_eig(2) = event.Value + imag(app.gl_eig(2))*1i;
            if app.conjugate_check_box.Value
                app.gl_eig(1) = real(app.gl_eig(2)) - imag(app.gl_eig(2))*1i;
                app.p1_slider.Value = event.Value;
            end
            app.simulate();
        end

        % Value changing function: imag_slider
        function imag_slider_changing(app, event)
            if app.conjugate_check_box.Value % If this is a conjugate pair 
                                           % then the other eigenvalue and 
                                           % slider need to be updated as 
                                           % well.
                app.gl_eig(1) = real(app.gl_eig(1)) + event.Value*1i;
                app.gl_eig(2) = real(app.gl_eig(1)) - event.Value*1i;
            end
            app.simulate();
        end

        % Value changing function: p3_slider
        function p3_slider_changing(app, event)
            if app.full_state_feedback_integrator_check_box.Value % If we're allowing 
                app.gl_eig(3) = complex(event.Value);
            end
            app.simulate();
        end

        % Value changing function: v_lim_slider
        function v_lim_slider_changing(app, event)
            app.control.v_lim = event.Value;
            app.simulate();
        end

        % Button pushed function: x_0_0
        function x_0_0_button_pushed(app, event)
                                                     % Many of the following
                                                     % callbacks follow this same
                                                     % pattern.
            app.x_0_slider.Value = 0;                % Update the value.
            app.x_0_slider_changing(app.x_0_slider); % Indicate the change.
        end

        % Button pushed function: v_0_0
        function v_0_0_button_pushed(app, event)
            app.v_0_slider.Value = 0;
            app.v_0_slider_changing(app.v_0_slider);
        end

        % Button pushed function: m_0
        function m_0_button_pushed(app, event)
            app.m_slider.Value = 0;
            app.m_slider_changing(app.m_slider);
        end

        % Button pushed function: k_0
        function k_0_button_pushed(app, event)
            app.k_slider.Value = 0;
            app.k_slider_changing(app.k_slider);
        end

        % Button pushed function: c_0
        function c_0_button_pushed(app, event)
            app.c_slider.Value = 0;
            app.c_slider_changing(app.c_slider);
        end

        % Button pushed function: F_max_0
        function F_max_0_button_pushed(app, event)
            app.F_max_slider.Value = 0;
            app.F_max_slider_changing(app.F_max_slider);
        end

        % Button pushed function: t_0
        function t_0_button_pushed(app, event)
            app.t_slider.Value = 0;
            app.t_slider_changing(app.t_slider);
        end

        % Button pushed function: r_0
        function r_0_button_pushed(app, event)
            app.r_slider.Value = 0;
            app.r_slider_changing(app.r_slider);
        end

        % Button pushed function: F_0
        function F_0_button_pushed(app, event)
            app.F_slider.Value = 0;
            app.F_slider_changing(app.F_slider);
        end

        % Button pushed function: P_0
        function P_0_button_pushed(app, event)
            app.P_slider.Value = 0;
            app.P_slider_changing(app.P_slider);
        end

        % Button pushed function: I_0
        function I_0_button_pushed(app, event)
            app.I_slider.Value = 0;
            app.I_slider_changing(app.I_slider);
        end

        % Button pushed function: D_0
        function D_0_button_pushed(app, event)
            app.D_slider.Value = 0;
            app.D_slider_changing(app.D_slider);
        end

        % Button pushed function: p1_0
        function p1_0_button_pushed(app, event)
            app.p1_slider.Value = 0;
            app.p1_slider_changing(app.p1_slider);
        end

        % Button pushed function: p2_0
        function p2_0_button_pushed(app, event)
            app.p2_slider.Value = 0;
            app.p2_slider_changing(app.p2_slider);
        end

        % Button pushed function: imag_0
        function imag_0_button_pushed(app, event)
            app.imag_slider.Value = 0;
            app.imag_slider_changing(app.imag_slider);
        end

        % Button pushed function: p3_0
        function p3_0_button_pushed(app, event)
            app.p3_slider.Value = 0;
            app.p3_slider_changing(app.p3_slider);
        end

        % Button pushed function: v_lim_0
        function v_lim_0_button_pushed(app, event)
            app.v_lim_slider.Value = 0;
            app.v_lim_slider_changing(app.v_lim_slider);
        end

        % Button pushed function: x_0_R
        function x_0_R_button_pushed(app, event)
            app.x_0_slider.Value = app.default_values.x_0;
            app.x_0_slider_changing(app.x_0_slider);
        end

        % Button pushed function: v_0_R
        function v_0_R_button_pushed(app, event)
            app.v_0_slider.Value = app.default_values.v_0;
            app.v_0_slider_changing(app.v_0_slider);
        end

        % Button pushed function: m_R
        function m_R_button_pushed(app, event)
            app.m_slider.Value = app.default_values.m;
            app.m_slider_changing(app.m_slider);
        end

        % Button pushed function: k_R
        function k_R_button_pushed(app, event)
            app.k_slider.Value = app.default_values.k;
            app.k_slider_changing(app.k_slider);
        end

        % Button pushed function: c_R
        function c_R_button_pushed(app, event)
            app.c_slider.Value = app.default_values.c;
            app.c_slider_changing(app.c_slider);
        end

        % Button pushed function: F_max_R
        function F_max_R_button_pushed(app, event)
            app.F_max_slider.Value = app.default_values.F_max;
            app.F_max_slider_changing(app.F_max_slider);
        end

        % Button pushed function: t_R
        function t_R_button_pushed(app, event)
            app.t_slider.Value = app.default_values.t;
            app.t_slider_changing(app.t_slider);
        end

        % Button pushed function: r_R
        function r_R_button_pushed(app, event)
            app.r_slider.Value = app.default_values.r;
            app.r_slider_changing(app.r_slider);
        end

        % Button pushed function: F_R
        function F_R_button_pushed(app, event)
            app.F_slider.Value = app.default_values.F;
            app.F_slider_changing(app.F_slider);
        end

        % Button pushed function: P_R
        function P_R_button_pushed(app, event)
            app.P_slider.Value = app.default_values.P;
            app.P_slider_changing(app.P_slider);
        end

        % Button pushed function: I_R
        function I_R_button_pushed(app, event)
            app.I_slider.Value = app.default_values.I;
            app.I_slider_changing(app.I_slider);
        end

        % Button pushed function: D_R
        function D_R_button_pushed(app, event)
            app.D_slider.Value = app.default_values.D;
            app.D_slider_changing(app.D_slider);
        end

        % Button pushed function: p1_R
        function p1_R_button_pushed(app, event)
            app.p1_slider.Value = real(app.default_values.poles(1));
            app.p1_slider_changing(app.p1_slider);
        end

        % Button pushed function: p2_R
        function p2_R_button_pushed(app, event)
            app.p2_slider.Value = real(app.default_values.poles(2));
            app.p2_slider_changing(app.p2_slider);
        end

        % Button pushed function: imag_R
        function imag_R_button_pushed(app, event)
            app.imag_slider.Value = imag(app.default_values.poles(1));
            app.imag_slider_changing(app.imag_slider);
        end

        % Button pushed function: p3_R
        function p3_R_button_pushed(app, event)
            app.p3_slider.Value = real(app.default_values.poles(3));
            app.p3_slider_changing(app.p3_slider);
        end

        % Button pushed function: v_lim_R
        function v_lim_R_button_pushed(app, event)
            app.v_lim_slider.Value = app.default_values.v_lim;
            app.v_lim_slider_changing(app.v_lim_slider);
        end

        % Button pushed function: play_button
        function play_button_pushed(app, event)
            if(app.paused)
                app.paused = false;
                set(app.play_button,'Text','Pause');
                app.animate()
            else
                app.paused = true;
                set(app.play_button,'Text','Play');
                % Set the starting point of the animation
                app.animation_offset = app.animation_offset ...
                                       + toc(app.animation_start); 
            end
        end

        % Value changed function: sat_lim_check_box
        function sat_lim_check_box_clicked(app, event)
            app.simulate(); % This and some following values don't need be 
                            % saved as the actual state of the check box 
                            % is checked when it's value is needed.
        end

        % Value changed function: open_loop_check_box
        function open_loop_check_box_clicked(app, event)
            app.type_selection('ol');
            app.simulate();
        end

        % Value changed function: PID_check_box
        function PID_check_box_clicked(app, event)
            app.type_selection('pid');
            app.simulate();
        end

        % Value changed function: full_state_feeback_check_box
        function full_state_feedback_check_box_clicked(app, event)
            app.type_selection('fsf');
            app.simulate();
        end

        % Value changed function: dc_gain_check_box
        function dc_gain_check_box_clicked(app, event)
            if app.dc_gain_check_box.Value && app.full_state_feedback_integrator_check_box.Value
                app.full_state_feedback_integrator_check_box.Value = false;
                app.gl_eig = app.gl_eig(1:2);
            end
            app.simulate();
        end

        % Value changed function: conjugate_check_box
        function conjugate_check_box_clicked(app, event)
            if app.conjugate_check_box.Value % If we are initalizing 
                                             % a conjugate pair
                % Move the eigenvalues to the same real point and with the
                % imaginary value indicated by the slider.
                app.gl_eig(1) = real(app.gl_eig(1)) ... 
                                + app.imag_slider.Value*1i;
                app.gl_eig(2) = real(app.gl_eig(1)) ...
                                - app.imag_slider.Value*1i;
                app.p2_slider.Value = app.p1_slider.Value; % Ensure slider 
                                                           % one matchs
                                                           % slider two.
            else
                % Remove the imaginary component of each eigenvalue.
                app.gl_eig(1) = complex(real(app.gl_eig(1)));
                app.gl_eig(2) = complex(real(app.gl_eig(1)));
            end
            app.simulate();
        end

        % Value changed function: 
        % full_state_feedback_integrator_check_box
        function full_state_feeback_integrator_check_box_clicked(app, event)
            % If we're adding an imaginary value.
            if app.full_state_feedback_integrator_check_box.Value
                % Append a new value to the imaginary values.
                app.gl_eig = [app.gl_eig(1:2),complex(app.p3_slider.Value)];
                app.dc_gain_check_box.Value = false; % Turn off the dc gain
                                                     % correction as the
                                                     % integrator does the
                                                     % same job.
            else
                app.gl_eig = app.gl_eig(1:2); % Remove the 3rd eigenvalue
            end
            app.simulate();
        end

        % Value changed function: antiwindup_check_box
        function antiwindup_check_box_clicked(app, event)
            app.simulate();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SystemLabel = uilabel(app.LeftPanel);
            app.SystemLabel.Text = 'System';
            app.cDampingNsmLabel = uilabel(app.LeftPanel);
            app.cDampingNsmLabel.Text = 'c - Damping (N*s/m)';
            app.c_slider = uislider(app.LeftPanel);
            app.c_slider.Limits = [-1 10];
            app.c_slider.ValueChangingFcn = createCallbackFcn(app, @c_slider_changing, true);
            app.c_slider.Value = 0.5;
            app.kSpringConstantNmLabel = uilabel(app.LeftPanel);
            app.kSpringConstantNmLabel.Text = 'k - Spring Constant (N/m)';
            app.k_slider = uislider(app.LeftPanel);
            app.k_slider.Limits = [-5 50];
            app.k_slider.ValueChangingFcn = createCallbackFcn(app, @k_slider_changing, true);
            app.k_slider.Value = 3;
            app.mMasskgLabel = uilabel(app.LeftPanel);
            app.mMasskgLabel.Text = 'm - Mass (kg)';
            app.m_slider = uislider(app.LeftPanel);
            app.m_slider.Limits = [0 10];
            app.m_slider.ValueChangingFcn = createCallbackFcn(app, @m_slider_changing, true);
            app.m_slider.Value = 5;
            app.v_0IntialVelocitymLabel = uilabel(app.LeftPanel);
            app.v_0IntialVelocitymLabel.Text = 'v_0 - Intial Velocity (m)';
            app.v_0_slider = uislider(app.LeftPanel);
            app.v_0_slider.Limits = [-4 4];
            app.v_0_slider.ValueChangingFcn = createCallbackFcn(app, @v_0_slider_changing, true);
            app.x_0InitialPositionmLabel = uilabel(app.LeftPanel);
            app.x_0InitialPositionmLabel.Text = 'x_0 - Initial Position (m)';
            app.x_0_slider = uislider(app.LeftPanel);
            app.x_0_slider.Limits = [-2 2];
            app.x_0_slider.ValueChangingFcn = createCallbackFcn(app, @x_0_slider_changing, true);
            app.m_0 = uibutton(app.LeftPanel, 'push');
            app.m_0.ButtonPushedFcn = createCallbackFcn(app, @m_0_button_pushed, true);
            app.m_0.Text = '0';
            app.v_0_0 = uibutton(app.LeftPanel, 'push');
            app.v_0_0.ButtonPushedFcn = createCallbackFcn(app, @v_0_0_button_pushed, true);
            app.v_0_0.Text = '0';
            app.x_0_0 = uibutton(app.LeftPanel, 'push');
            app.x_0_0.ButtonPushedFcn = createCallbackFcn(app, @x_0_0_button_pushed, true);
            app.x_0_0.Text = '0';
            app.k_0 = uibutton(app.LeftPanel, 'push');
            app.k_0.ButtonPushedFcn = createCallbackFcn(app, @k_0_button_pushed, true);
            app.k_0.Text = '0';
            app.c_0 = uibutton(app.LeftPanel, 'push');
            app.c_0.ButtonPushedFcn = createCallbackFcn(app, @c_0_button_pushed, true);
            app.c_0.Text = '0';
            app.x_0_R = uibutton(app.LeftPanel, 'push');
            app.x_0_R.ButtonPushedFcn = createCallbackFcn(app, @x_0_R_button_pushed, true);
            app.x_0_R.Text = 'R';
            app.v_0_R = uibutton(app.LeftPanel, 'push');
            app.v_0_R.ButtonPushedFcn = createCallbackFcn(app, @v_0_R_button_pushed, true);
            app.v_0_R.Text = 'R';
            app.m_R = uibutton(app.LeftPanel, 'push');
            app.m_R.ButtonPushedFcn = createCallbackFcn(app, @m_R_button_pushed, true);
            app.m_R.Text = 'R';
            app.k_R = uibutton(app.LeftPanel, 'push');
            app.k_R.ButtonPushedFcn = createCallbackFcn(app, @k_R_button_pushed, true);
            app.k_R.Text = 'R';
            app.c_R = uibutton(app.LeftPanel, 'push');
            app.c_R.ButtonPushedFcn = createCallbackFcn(app, @c_R_button_pushed, true);
            app.c_R.Text = 'R';
            app.F_max_0 = uibutton(app.LeftPanel, 'push');
            app.F_max_0.ButtonPushedFcn = createCallbackFcn(app, @F_max_0_button_pushed, true);
            app.F_max_0.Text = '0';
            app.F_max_R = uibutton(app.LeftPanel, 'push');
            app.F_max_R.ButtonPushedFcn = createCallbackFcn(app, @F_max_R_button_pushed, true);
            app.F_max_R.Text = 'R';
            app.F_maxSatLimMLabel = uilabel(app.LeftPanel);
            app.F_maxSatLimMLabel.Text = 'F_max Sat Lim (M)';
            app.F_max_slider = uislider(app.LeftPanel);
            app.F_max_slider.Limits = [0 10];
            app.F_max_slider.ValueChangingFcn = createCallbackFcn(app, @F_max_slider_changing, true);
            app.F_max_slider.Value = 6;
            app.sat_lim_check_box = uicheckbox(app.LeftPanel);
            app.sat_lim_check_box.ValueChangedFcn = createCallbackFcn(app, @sat_lim_check_box_clicked, true);
            app.sat_lim_check_box.Text = 'Sat Lim';
            app.sat_lim_check_box.Value = true;
            app.s_plane = uiaxes(app.LeftPanel);
            title(app.s_plane, 'System Poles')
            xlabel(app.s_plane, '')
            ylabel(app.s_plane, '')
            app.s_plane.XLim = [-6 1];
            app.s_plane.YLim = [-3 3];
            app.s_plane.XAxisLocation = 'origin';
            app.s_plane.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.s_plane.XTickLabel = {'-5'; '-4'; '-3'; '-2'; '-1'; ''; '1'; '2'; '3'; '4'; ''};
            app.s_plane.YAxisLocation = 'origin';
            app.s_plane.YTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.s_plane.YTickLabel = {'-5'; '-4'; '-3'; '-2'; ''; ''; '1'; '2'; '3'; '4'; ''};
            app.CenterPanel = uipanel(app.GridLayout);
            app.animation = uiaxes(app.CenterPanel);
            title(app.animation, 'Animation')
            xlabel(app.animation, '')
            ylabel(app.animation, '')
            app.animation.XLim = [-3 3];
            app.animation.YLim = [0 1];
            app.animation.XTick = [-3 -2 -1 0 1 2 3];
            app.animation.YTick = [];
            app.x_plot = uiaxes(app.CenterPanel);
            title(app.x_plot, '')
            xlabel(app.x_plot, '')
            ylabel(app.x_plot, 'x (m)')
            app.x_plot.XTickLabel = '';
            app.e_plot = uiaxes(app.CenterPanel);
            title(app.e_plot, '')
            xlabel(app.e_plot, '')
            ylabel(app.e_plot, 'e (m)')
            app.e_plot.XTickLabel = '';
            app.v_plot = uiaxes(app.CenterPanel);
            title(app.v_plot, '')
            xlabel(app.v_plot, '')
            ylabel(app.v_plot, 'v (m/s)')
            app.v_plot.XTickLabel = '';
            app.F_plot = uiaxes(app.CenterPanel);
            title(app.F_plot, '')
            xlabel(app.F_plot, 't - Time (s)')
            ylabel(app.F_plot, 'F (N)')
            app.I_plot = uiaxes(app.CenterPanel);
            title(app.I_plot, '')
            xlabel(app.I_plot, '')
            ylabel(app.I_plot, 'I (m*s)')
            app.I_plot.XTickLabel = '';
            app.play_button = uibutton(app.CenterPanel, 'push');
            app.play_button.ButtonPushedFcn = createCallbackFcn(app, @play_button_pushed, true);
            app.play_button.Text = 'Play';
            app.t_0 = uibutton(app.CenterPanel, 'push');
            app.t_0.ButtonPushedFcn = createCallbackFcn(app, @t_0_button_pushed, true);
            app.t_0.Text = '0';
            app.t_R = uibutton(app.CenterPanel, 'push');
            app.t_R.ButtonPushedFcn = createCallbackFcn(app, @t_R_button_pushed, true);
            app.t_R.Text = 'R';
            app.tAnimationTimesLabel = uilabel(app.CenterPanel);
            app.tAnimationTimesLabel.Text = 't - Animation Time (s)';
            app.t_slider = uislider(app.CenterPanel);
            app.t_slider.Limits = [0 10];
            app.t_slider.ValueChangingFcn = createCallbackFcn(app, @t_slider_changing, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.ControllerLabel = uilabel(app.RightPanel);
            app.ControllerLabel.Text = 'Controller';
            app.open_loop_check_box = uicheckbox(app.RightPanel);
            app.open_loop_check_box.ValueChangedFcn = createCallbackFcn(app, @open_loop_check_box_clicked, true);
            app.open_loop_check_box.Text = 'Open Loop';
            app.open_loop_check_box.Value = true;
            app.PID_check_box = uicheckbox(app.RightPanel);
            app.PID_check_box.ValueChangedFcn = createCallbackFcn(app, @PID_check_box_clicked, true);
            app.PID_check_box.Text = 'PID';
            app.full_state_feeback_check_box = uicheckbox(app.RightPanel);
            app.full_state_feeback_check_box.ValueChangedFcn = createCallbackFcn(app, @full_state_feedback_check_box_clicked, true);
            app.full_state_feeback_check_box.Text = 'Full State';
            app.FInputNLabel = uilabel(app.RightPanel);
            app.FInputNLabel.Text = 'F - Input (N)';
            app.F_slider = uislider(app.RightPanel);
            app.F_slider.Limits = [0 10];
            app.F_slider.ValueChangingFcn = createCallbackFcn(app, @F_slider_changing, true);
            app.PProportionalGainLabel = uilabel(app.RightPanel);
            app.PProportionalGainLabel.Text = 'P - Proportional Gain';
            app.P_slider = uislider(app.RightPanel);
            app.P_slider.Limits = [-1 20];
            app.P_slider.ValueChangingFcn = createCallbackFcn(app, @P_slider_changing, true);
            app.IIntegratorGainLabel = uilabel(app.RightPanel);
            app.IIntegratorGainLabel.Text = 'I - Integrator Gain';
            app.I_slider = uislider(app.RightPanel);
            app.I_slider.Limits = [-1 10];
            app.I_slider.ValueChangingFcn = createCallbackFcn(app, @I_slider_changing, true);
            app.DDerivativeGainLabel = uilabel(app.RightPanel);
            app.DDerivativeGainLabel.Text = 'D - Derivative Gain';
            app.D_slider = uislider(app.RightPanel);
            app.D_slider.Limits = [-1 20];
            app.D_slider.ValueChangingFcn = createCallbackFcn(app, @D_slider_changing, true);
            app.rCommandmLabel = uilabel(app.RightPanel);
            app.rCommandmLabel.Text = 'r - Command (m)';
            app.r_slider = uislider(app.RightPanel);
            app.r_slider.Limits = [-2 2];
            app.r_slider.ValueChangingFcn = createCallbackFcn(app, @r_slider_changing, true);
            app.r_slider.Value = 1;
            app.r_0 = uibutton(app.RightPanel, 'push');
            app.r_0.ButtonPushedFcn = createCallbackFcn(app, @r_0_button_pushed, true);
            app.r_0.Text = '0';
            app.F_0 = uibutton(app.RightPanel, 'push');
            app.F_0.ButtonPushedFcn = createCallbackFcn(app, @F_0_button_pushed, true);
            app.F_0.Text = '0';
            app.P_0 = uibutton(app.RightPanel, 'push');
            app.P_0.ButtonPushedFcn = createCallbackFcn(app, @P_0_button_pushed, true);
            app.P_0.Text = '0';
            app.I_0 = uibutton(app.RightPanel, 'push');
            app.I_0.ButtonPushedFcn = createCallbackFcn(app, @I_0_button_pushed, true);
            app.I_0.Text = '0';
            app.D_0 = uibutton(app.RightPanel, 'push');
            app.D_0.ButtonPushedFcn = createCallbackFcn(app, @D_0_button_pushed, true);
            app.D_0.Text = '0';
            app.p1_0 = uibutton(app.RightPanel, 'push');
            app.p1_0.ButtonPushedFcn = createCallbackFcn(app, @p1_0_button_pushed, true);
            app.p1_0.Text = '0';
            app.r_R = uibutton(app.RightPanel, 'push');
            app.r_R.ButtonPushedFcn = createCallbackFcn(app, @r_R_button_pushed, true);
            app.r_R.Text = 'R';
            app.F_R = uibutton(app.RightPanel, 'push');
            app.F_R.ButtonPushedFcn = createCallbackFcn(app, @F_R_button_pushed, true);
            app.F_R.Text = 'R';
            app.P_R = uibutton(app.RightPanel, 'push');
            app.P_R.ButtonPushedFcn = createCallbackFcn(app, @P_R_button_pushed, true);
            app.P_R.Text = 'R';
            app.I_R = uibutton(app.RightPanel, 'push');
            app.I_R.ButtonPushedFcn = createCallbackFcn(app, @I_R_button_pushed, true);
            app.I_R.Text = 'R';
            app.D_R = uibutton(app.RightPanel, 'push');
            app.D_R.ButtonPushedFcn = createCallbackFcn(app, @D_R_button_pushed, true);
            app.D_R.Text = 'R';
            app.p1_R = uibutton(app.RightPanel, 'push');
            app.p1_R.ButtonPushedFcn = createCallbackFcn(app, @p1_R_button_pushed, true);
            app.p1_R.Text = 'R';
            app.v_limAntiwindupmsLabel = uilabel(app.RightPanel);
            app.v_limAntiwindupmsLabel.Text = 'v_lim - Anti-windup (m/s)';
            app.v_lim_slider = uislider(app.RightPanel);
            app.v_lim_slider.Limits = [0 2];
            app.v_lim_slider.ValueChangingFcn = createCallbackFcn(app, @v_lim_slider_changing, true);
            app.v_lim_slider.Value = 0.4;
            app.antiwindup_check_box = uicheckbox(app.RightPanel);
            app.antiwindup_check_box.ValueChangedFcn = createCallbackFcn(app, @antiwindup_check_box_clicked, true);
            app.antiwindup_check_box.Text = 'Anti-windup';
            app.v_lim_0 = uibutton(app.RightPanel, 'push');
            app.v_lim_0.ButtonPushedFcn = createCallbackFcn(app, @v_lim_0_button_pushed, true);
            app.v_lim_0.Text = '0';
            app.v_lim_R = uibutton(app.RightPanel, 'push');
            app.v_lim_R.ButtonPushedFcn = createCallbackFcn(app, @v_lim_R_button_pushed, true);
            app.v_lim_R.Text = 'R';
            app.FeedbackLabel = uilabel(app.RightPanel);
            app.FeedbackLabel.Text = 'Feedback';
            app.full_state_feedback_integrator_check_box = uicheckbox(app.RightPanel);
            app.full_state_feedback_integrator_check_box.ValueChangedFcn = createCallbackFcn(app, @full_state_feeback_integrator_check_box_clicked, true);
            app.full_state_feedback_integrator_check_box.Text = 'Integrator';
            app.Pole1RealComponentLabel = uilabel(app.RightPanel);
            app.Pole1RealComponentLabel.Text = 'Pole 1 Real Component';
            app.p1_slider = uislider(app.RightPanel);
            app.p1_slider.Limits = [-6 1];
            app.p1_slider.ValueChangingFcn = createCallbackFcn(app, @p1_slider_changing, true);
            app.p2_0 = uibutton(app.RightPanel, 'push');
            app.p2_0.ButtonPushedFcn = createCallbackFcn(app, @p2_0_button_pushed, true);
            app.p2_0.Text = '0';
            app.p2_R = uibutton(app.RightPanel, 'push');
            app.p2_R.ButtonPushedFcn = createCallbackFcn(app, @p2_R_button_pushed, true);
            app.p2_R.Text = 'R';
            app.Pole2RealComponentLabel = uilabel(app.RightPanel);
            app.Pole2RealComponentLabel.Text = 'Pole 2 Real Component';
            app.p2_slider = uislider(app.RightPanel);
            app.p2_slider.Limits = [-6 1];
            app.p2_slider.ValueChangingFcn = createCallbackFcn(app, @p2_slider_changing, true);
            app.p3_0 = uibutton(app.RightPanel, 'push');
            app.p3_0.ButtonPushedFcn = createCallbackFcn(app, @p3_0_button_pushed, true);
            app.p3_0.Text = '0';
            app.p3_R = uibutton(app.RightPanel, 'push');
            app.p3_R.ButtonPushedFcn = createCallbackFcn(app, @p3_R_button_pushed, true);
            app.p3_R.Text = 'R';
            app.IntegratorRealComponentLabel = uilabel(app.RightPanel);
            app.IntegratorRealComponentLabel.Text = 'Integrator Real Component';
            app.p3_slider = uislider(app.RightPanel);
            app.p3_slider.Limits = [-6 1];
            app.p3_slider.ValueChangingFcn = createCallbackFcn(app, @p3_slider_changing, true);
            app.conjugate_check_box = uicheckbox(app.RightPanel);
            app.conjugate_check_box.ValueChangedFcn = createCallbackFcn(app, @conjugate_check_box_clicked, true);
            app.conjugate_check_box.Text = 'Conjugate Pair';
            app.imag_0 = uibutton(app.RightPanel, 'push');
            app.imag_0.ButtonPushedFcn = createCallbackFcn(app, @imag_0_button_pushed, true);
            app.imag_0.Text = '0';
            app.imag_R = uibutton(app.RightPanel, 'push');
            app.imag_R.ButtonPushedFcn = createCallbackFcn(app, @imag_R_button_pushed, true);
            app.imag_R.Text = 'R';
            app.Poles12ImaginaryComponentLabel = uilabel(app.RightPanel);
            app.Poles12ImaginaryComponentLabel.Text = 'Poles 1 & 2 Imaginary Component';
            app.imag_slider = uislider(app.RightPanel);
            app.imag_slider.Limits = [0 3];
            app.imag_slider.ValueChangingFcn = createCallbackFcn(app, @imag_slider_changing, true);
            app.dc_gain_check_box = uicheckbox(app.RightPanel);
            app.dc_gain_check_box.ValueChangedFcn = createCallbackFcn(app, @dc_gain_check_box_clicked, true);
            app.dc_gain_check_box.Text = 'Make DC Gain 1';
            app.dc_gain_check_box.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spring_mass_damper_simulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end